classdef DornaTeach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LOADROBOTButton           matlab.ui.control.Button
        YawLabel                  matlab.ui.control.Label
        PitchLabel                matlab.ui.control.Label
        RollLabel                 matlab.ui.control.Label
        ZLabel                    matlab.ui.control.Label
        YLabel                    matlab.ui.control.Label
        XLabel                    matlab.ui.control.Label
        Z_Text                    matlab.ui.control.TextArea
        Y_Text                    matlab.ui.control.TextArea
        X_Text                    matlab.ui.control.TextArea
        Yaw_Text                  matlab.ui.control.TextArea
        Pitch_Text                matlab.ui.control.TextArea
        Roll_Text                 matlab.ui.control.TextArea
        Joint5Text                matlab.ui.control.TextArea
        Joint4Text                matlab.ui.control.TextArea
        Joint3Text                matlab.ui.control.TextArea
        Joint2Text                matlab.ui.control.TextArea
        Joint1Text                matlab.ui.control.TextArea
        Image                     matlab.ui.control.Image
        ResetButton               matlab.ui.control.Button
        ZSliderLabel              matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        YSlider                   matlab.ui.control.Slider
        ZSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        XSliderLabel              matlab.ui.control.Label
        JOINT1Knob                matlab.ui.control.Knob
        JOINT2Knob                matlab.ui.control.Knob
        JOINT3Knob                matlab.ui.control.Knob
        JOINT4Knob                matlab.ui.control.Knob
        JOINT5Knob                matlab.ui.control.Knob
        JOINT5KnobLabel           matlab.ui.control.Label
        JOINT4KnobLabel           matlab.ui.control.Label
        JOINT3KnobLabel           matlab.ui.control.Label
        JOINT2KnobLabel           matlab.ui.control.Label
        JOINT1KnobLabel           matlab.ui.control.Label
        JointDegCartesianmmLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        % set path
        path = addpath(genpath('./ProjectProperties'));
        robot % Description
        max_square = 0.4; % square of maximum distance the arm can stretch
        min_square = 0.04;  % square of minimum distance the arm can retreat
    end

    methods (Access = private)

        % limit the robot from conflicting the body or overstretch
        function a = LimitRange(app,newPos,poseCurrent,option)
            a = newPos;

            switch option
                case 'x'
                    b = poseCurrent(2,4);
                    c = poseCurrent(3,4);
                    r = (a-0.77)^2 + b^2;
                case 'y'
                    b = poseCurrent(1,4)-0.77;
                    c = poseCurrent(3,4);
                    r = a^2 + b^2;
                case 'z'
                    b = poseCurrent(1,4);
                    c = poseCurrent(2,4);
                    r = 0;
            end

            if r
                if r < app.min_square % check for self-conflict
                    if option == 'y'
                        if a > 0 || (a < 0 && poseCurrent(1,4)> 0)
                            a = real(sqrt(app.min_square - b^2));
                        else
                            a = -real(sqrt(app.min_square - b^2));
                        end
                    elseif option == 'x'
                        a = real(sqrt(app.min_square - b^2)) + 0.77;
                    end

                end

                if (a^2 + b^2 + c^2) > app.max_square % check for overstretch
                    if a > 0
                        a = real(sqrt(app.max_square - b^2 - c^2));
                    else
                        a = -real(sqrt(app.max_square - b^2 - c^2));
                    end
                end
            end
        end

        function GetPosition(app)
            qCurrent = app.robot.model.getpos;
            poseCurrent = app.robot.model.fkine(qCurrent);
            orientationCurrent = rad2deg(tr2rpy(poseCurrent));

            qCurrent = rad2deg(qCurrent);
            app.Joint1Text.Value = num2str(qCurrent(1));
            app.Joint2Text.Value = num2str(qCurrent(2));
            app.Joint3Text.Value = num2str(qCurrent(3));
            app.Joint4Text.Value = num2str(qCurrent(4));
            app.Joint5Text.Value = num2str(qCurrent(5));

            app.X_Text.Value = num2str(poseCurrent(1,4));
            app.Y_Text.Value = num2str(poseCurrent(2,4));
            app.Z_Text.Value = num2str(poseCurrent(3,4));

            app.Roll_Text.Value = num2str(orientationCurrent(1));
            app.Pitch_Text.Value = num2str(orientationCurrent(2));
            app.Yaw_Text.Value = num2str(orientationCurrent(3));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: JOINT1Knob
        function JOINT1KnobValueChanging(app, event)
            if ~isempty(app.robot)
                q1Target = pi/180* event.Value;
                steps = 10;
                jointCurrent = app.robot.model.getpos;

                qTrajectory = repmat(jointCurrent,steps,1);
                q1 = lspb(jointCurrent(1),q1Target,steps);
                qTrajectory(:,1) = q1;

                app.robot.MoveRobot(qTrajectory);
                app.GetPosition();
            end
        end

        % Value changed function: JOINT2Knob
        function JOINT2KnobValueChanging(app, event)
            if ~isempty(app.robot)
                q2Target = pi/180* event.Value;
                steps = 10;
                jointCurrent = app.robot.model.getpos;

                qTrajectory = repmat(jointCurrent,steps,1);
                q2 = lspb(jointCurrent(2),q2Target,steps);
                qTrajectory(:,2) = q2;

                app.robot.MoveRobot(qTrajectory);
                app.GetPosition();
            end
        end

        % Value changed function: JOINT3Knob
        function JOINT3KnobValueChanging(app, event)
            if ~isempty(app.robot)
                q3Target = pi/180* event.Value;
                steps = 10;
                jointCurrent = app.robot.model.getpos;

                qTrajectory = repmat(jointCurrent,steps,1);
                q3 = lspb(jointCurrent(3),q3Target,steps);
                qTrajectory(:,3) = q3;

                app.robot.MoveRobot(qTrajectory);
                app.GetPosition();
            end
        end

        % Value changed function: JOINT4Knob
        function JOINT4KnobValueChanging(app, event)
            if ~isempty(app.robot)
                q4Target = pi/180* event.Value;
                steps = 10;
                jointCurrent = app.robot.model.getpos;

                qTrajectory = repmat(jointCurrent,steps,1);
                q4 = lspb(jointCurrent(4),q4Target,steps);
                qTrajectory(:,4) = q4;

                app.robot.MoveRobot(qTrajectory);
                app.GetPosition();
            end
        end

        % Value changed function: JOINT5Knob
        function JOINT5KnobValueChanging(app, event)
            if ~isempty(app.robot)
                q5Target = pi/180* event.Value;
                steps = 10;
                jointCurrent = app.robot.model.getpos;

                qTrajectory = repmat(jointCurrent,steps,1);
                q5 = lspb(jointCurrent(5),q5Target,steps);
                qTrajectory(:,5) = q5;

                app.robot.MoveRobot(qTrajectory);
                app.GetPosition();
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if ~isempty(app.robot)
                app.robot.MoveRobot(app.robot.jointDefault2);
                app.GetPosition();
            end
        end

        % Value changed function: XSlider
        function XSliderValueChanging(app, event)
            if ~isempty(app.robot)
                jointCurrent = app.robot.model.getpos;
                poseCurrent = app.robot.model.fkine(jointCurrent);

                xNewPos = app.LimitRange(event.Value/1000,poseCurrent,'x');
                
                poseTarget = transl(xNewPos-poseCurrent(1,4),0,0)*poseCurrent;
                
                RMRCMotion(app.robot,poseTarget,40,deg2rad(450));

                app.GetPosition();
            end
        end

        % Value changed function: YSlider
        function YSliderValueChanging(app, event)
            if ~isempty(app.robot)
                jointCurrent = app.robot.model.getpos;
                poseCurrent = app.robot.model.fkine(jointCurrent);

                yNewPos = app.LimitRange(event.Value/1000,poseCurrent,'y');

                poseTarget = transl(0,yNewPos-poseCurrent(2,4),0)*poseCurrent;
                RMRCMotion(app.robot,poseTarget,50,deg2rad(450));

                app.GetPosition();
            end
        end

        % Value changed function: ZSlider
        function ZSliderValueChanging(app, event)
            if ~isempty(app.robot)
                jointCurrent = app.robot.model.getpos;
                poseCurrent = app.robot.model.fkine(jointCurrent);

                zNewPos = app.LimitRange(event.Value/1000,poseCurrent,'z');

                poseTarget = transl(0,0,zNewPos-poseCurrent(3,4))*poseCurrent;
                RMRCMotion(app.robot,poseTarget,50,deg2rad(450));

                app.GetPosition();
            end
        end

        % Button pushed function: LOADROBOTButton
        function LOADROBOTButtonPushed(app, event)
            if isempty(app.robot)
                app.robot = Dorna2Robot(2);
                app.robot.model.base = transl([0.77 0 0]) * trotz(pi);
                app.robot.MoveRobot(app.robot.model.getpos);
                app.GetPosition();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JointDegCartesianmmLabel = uilabel(app.UIFigure);
            app.JointDegCartesianmmLabel.Text = 'Joint (Deg)  Cartesian (mm)';
            app.JOINT1KnobLabel = uilabel(app.UIFigure);
            app.JOINT1KnobLabel.Text = 'JOINT 1';
            app.JOINT2KnobLabel = uilabel(app.UIFigure);
            app.JOINT2KnobLabel.Text = 'JOINT 2';
            app.JOINT3KnobLabel = uilabel(app.UIFigure);
            app.JOINT3KnobLabel.Text = 'JOINT 3';
            app.JOINT4KnobLabel = uilabel(app.UIFigure);
            app.JOINT4KnobLabel.Text = 'JOINT 4';
            app.JOINT5KnobLabel = uilabel(app.UIFigure);
            app.JOINT5KnobLabel.Text = 'JOINT 5';
            app.JOINT5Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT5Knob.Limits = [-360 360];
            app.JOINT5Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT5KnobValueChanging, true);
            app.JOINT4Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT4Knob.Limits = [-135 135];
            app.JOINT4Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT4KnobValueChanging, true);
            app.JOINT3Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT3Knob.Limits = [-142 142];
            app.JOINT3Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT3KnobValueChanging, true);
            app.JOINT2Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT2Knob.Limits = [-180 90];
            app.JOINT2Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT2KnobValueChanging, true);
            app.JOINT1Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT1Knob.Limits = [-160 180];
            app.JOINT1Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT1KnobValueChanging, true);
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [80 450];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZSlider.Value = 300;
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-310 310];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [350 1140];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.Value = 500;
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ProjectProperties', 'Images', 'Dorna.png');
            app.Joint1Text = uitextarea(app.UIFigure);
            app.Joint2Text = uitextarea(app.UIFigure);
            app.Joint3Text = uitextarea(app.UIFigure);
            app.Joint4Text = uitextarea(app.UIFigure);
            app.Joint5Text = uitextarea(app.UIFigure);
            app.Roll_Text = uitextarea(app.UIFigure);
            app.Pitch_Text = uitextarea(app.UIFigure);
            app.Yaw_Text = uitextarea(app.UIFigure);
            app.X_Text = uitextarea(app.UIFigure);
            app.Y_Text = uitextarea(app.UIFigure);
            app.Z_Text = uitextarea(app.UIFigure);
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.RollLabel = uilabel(app.UIFigure);
            app.RollLabel.Text = 'Roll';
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch';
            app.YawLabel = uilabel(app.UIFigure);
            app.YawLabel.Text = 'Yaw';
            app.LOADROBOTButton = uibutton(app.UIFigure, 'push');
            app.LOADROBOTButton.ButtonPushedFcn = createCallbackFcn(app, @LOADROBOTButtonPushed, true);
            app.LOADROBOTButton.Text = 'LOAD ROBOT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DornaTeach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end