classdef KitchenRobots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DemoVisualServoingButton    matlab.ui.control.Button
        STATUSLabel                 matlab.ui.control.Label
        WorkStatus                  matlab.ui.control.TextArea
        VegetableTypeButtonGroup    matlab.ui.container.ButtonGroup
        CucumberButton              matlab.ui.control.ToggleButton
        CarrotButton                matlab.ui.control.ToggleButton
        TeachDornaButton            matlab.ui.control.Button
        TeachDobotButton            matlab.ui.control.Button
        ActiveRobotTextArea         matlab.ui.control.TextArea
        ActiveRobotTextAreaLabel    matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        InstallResetRobotsButton    matlab.ui.control.Button
        LightCurtainsInformSwitch   matlab.ui.control.Switch
        LightCurtainsInformLabel    matlab.ui.control.Label
        BeaconLightLampLabel        matlab.ui.control.Label
        BeaconLightLamp             matlab.ui.control.Lamp
        SetupEnvironmentButton      matlab.ui.control.Button
        LoadCollisionObjectButton   matlab.ui.control.Button
        SimulatedArmPositionSlider  matlab.ui.control.Slider
        EStopButton                 matlab.ui.control.Button
        ResumeButton                matlab.ui.control.Button
        textLightCurtainInfo        matlab.ui.control.TextArea
        textWorkSpaceInfo           matlab.ui.control.TextArea
        PositionSliderLabel         matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
    end

    properties (Access = private)
        %> Set path
        path = addpath(genpath('./ProjectProperties'));

        %> Figure
        figName = 'IR_A2_Workspace';

        %> Default status of the buttons
        EstopPushed = false;
        ResumePushed = true;

        %> Two robots
        robot1 % Dobot Magician
        robot2 % Dorna 2 Robot

        %> Vegetable type
        veggieType = 'Carrot'

        %> Light Curtain status and properties
        lightCurtainON = false;
        gridSize = 50;
        gridLeft % point cloud for left curtain
        gridRight % point cloud for the right curtain

        lightCurtainDetect = false % true when an object touches the light curtains

        %> Collision object
        collisionObject

        %> Working point clouds for the unsafe zone of robots
        pointCloudDobot
        pointCloudDorna

        workspaceDobotDetect = false; % true when an object entering the Dobot's workspace
        workspaceDornaDetect = false; % true when an object entering the Dorna's workspace

        pointCloudInsideDobot % points of the point cloud that has external object
        pointCloudInsideDorna

        %> the step at which the robot starts avoiding collision
        stepCollisionDobot
    end

    %% Private methods no Callbacks
    methods (Access = private)

        %% function for straight line RMRC motion
        function RMRCMotion(app,robot,poseFinal,steps,speedMax,object,objectTr)
            % robot: robot object, which has MoveRobot method
            % qFinal: final joint state of the robot
            % steps : desired steps for the trajectory from current -> final Pose
            % maxspeed: minimum Max speed among the joints
            % object: gripped object for robot's gripper of the class 'Veggie'
            % objectTr: object's transform seen by the robot

            % safe step of time for not exceed each joint's max speed
            timestep = 0.05;

            % manipulability threshold
            mani_threshold = 0.0044;

            % max damping coefficient
            damping_coefficient_MAX = 0.5;

            % get initial pose
            poseCurrent = robot.model.fkine(robot.model.getpos);

            pointCurrent = poseCurrent(1:3,4);
            pointFinal = poseFinal(1:3,4);
            errorDisplacement = norm(pointFinal - pointCurrent);

            objectExist = false;

            if exist('object','var')
                objectExist = true;
                if ~exist('objectTr','var')
                    objectTr = eye(4);
                end
            end

            stepCount = 0;

            %% track the trajectory by RRMC
            while errorDisplacement > 0.005
                % trace the end-effector
                % plot3(poseCurrent(1,4),poseCurrent(2,4),poseCurrent(3,4),'r.');

                % current joint state
                qCurrent = robot.model.getpos;

                % current difference to the final position
                distanceDiff = transl(poseFinal) - transl(poseCurrent);
                angleDiff = tr2rpy(poseFinal) - tr2rpy(poseCurrent);

                % disallow the angular difference > pi for Dorna
                if isa(robot,'Dorna2Robot')
                    for i =1:3
                        if abs(angleDiff(i)) >= 3.14
                            angleDiff(i) = 0;
                        end
                    end
                end

                % desired spatial velocity
                u = (distanceDiff/(steps-stepCount))/timestep;
                omega = (angleDiff/(steps-stepCount))/timestep;

                % Jacobian and manipulability
                J = robot.model.jacob0(qCurrent);
                mani =  robot.model.maniplty(qCurrent);

                % joint velocity
                qd = pinv(J) * [u; omega'];

                % reduce singularity if exists
                if (mani < mani_threshold)
                    damping_coefficient = (1-(mani/mani_threshold)^2)*damping_coefficient_MAX;
                    J_DLS = J'/(J*J'+ damping_coefficient*eye(6));    % damped least square Jacobian

                    disp("RMRC DLS applied!");

                    % joint velocity after DLS
                    qd = J_DLS * [u; omega'];
                end

                % next position
                qNext = qCurrent + qd'*timestep;

                % check whether 'qNext' includes off-limit joint:
                checkLimit = CheckJointLimit(robot.model,qNext);
                if  checkLimit <= robot.model.n
                    disp(['RMRC step ',num2str(stepCount),'. Warning: exceed joint limit at joint ', num2str(checkLimit), ...
                        '. A patch using IK solution is applied!']);

                    % replace the invalid 'qNext' by an IK solution:
                    poseNext = robot.model.fkine(qNext);
                    qNext = robot.model.ikcon(poseNext,qCurrent);

                    % get the required velocity
                    qd = (qNext - qCurrent)/timestep;
                end

                % scale the velocity if any joint exceed max speed
                [~,scale]= ScaleJointSpeed(qd,speedMax);

                if scale
                    disp(['RMRC step ',num2str(stepCount),', velocity is scale down ',num2str(scale)]);
                end

                % qNext = qd * (timestep*scale);

                % check working condition
                app.CheckWorkingStatus();

                % return if something entering the robot's point cloud
                % workspace at a specific step on the path
                if isa(robot,'DobotMagicianwithGripper')
                    if stepCount == app.stepCollisionDobot
                        app.stepCollisionDobot = -1; % clear the avoidance variable
                        return
                    end
                end

                % move robot
                robot.MoveRobot(qNext);

                % retrieve the current pose
                poseCurrent = robot.model.fkine(robot.model.getpos);

                % move the object if exists
                if objectExist
                    object.Update(poseCurrent*objectTr);
                end

                % calculate the current error to the goal
                pointCurrent = poseCurrent(1:3,4);
                errorDisplacement = norm(pointFinal - pointCurrent);

                stepCount = stepCount+1;
            end

            % disp(['Current error is ',num2str(1000* errorDisplacement),'mm.']);
        end

        %% Unsafe working zone cylinder pointclouds for 2 robots
        function CreateRobotWorkingZone(app)
            steps = 30;

            % height of the cylinders
            z1 = linspace(0,0.4,steps)';
            z2 = linspace(0,0.5,steps)';

            % radius of the cylinders
            r1 = linspace(0.3,0.01,steps);
            r2 = linspace(0.35,0.01,steps);

            app.pointCloudDobot = nan(steps^3,3);
            app.pointCloudDorna = nan(steps^3,3);
            
            for i = 1:steps
                [x1,y1,~] = cylinder(r1(i),steps - 1);
                [x2,y2,~] = cylinder(r2(i),steps - 1);

                x1 = x1(1,:)';
                y1 = y1(1,:)';

                x2 = x2(1,:)';
                y2 = y2(1,:)';

                p1 = nan(steps^2,3);
                p2 = nan(steps^2,3);

                for j = 1:steps
                    p1((steps*(j-1)+1):steps*j,:) = [x1 y1 repmat(z1(j),steps,1)];
                    p2((steps*(j-1)+1):steps*j,:) = [x2 y2 repmat(z2(j),steps,1)];
                end

                app.pointCloudDobot(steps^2*(i-1)+1:steps^2*i,:) = p1;
                app.pointCloudDorna(steps^2*(i-1)+1:steps^2*i,:) = p2;

            end
            app.pointCloudDorna(:,1) = app.pointCloudDorna(:,1) + 0.77; 

%             plot3(app.pointCloudDobot(:,1),app.pointCloudDobot(:,2),app.pointCloudDobot(:,3))
%             plot3(app.pointCloudDorna(:,1),app.pointCloudDorna(:,2),app.pointCloudDorna(:,3))
        end


        function CheckWorkingStatus(app)
            % check for E-stop and Resume button pressed:
            if app.EstopPushed == true
                app.WorkStatus.Value = 'E-Stop pushed! Stop Working.';
                while app.EstopPushed == true
                    pause(1)
                    if app.ResumePushed == true
                        app.WorkStatus.Value = 'Working in progress...';
                        break;
                    end
                end
            end

            % check for object entering the workspace detected by light
            % curtains
            if app.lightCurtainON == true
                while app.lightCurtainDetect == true
                    app.WorkStatus.Value = 'Object enters workspace. Stop Working.';
                    pause(1)
                    if app.lightCurtainDetect == false
                        app.WorkStatus.Value = 'Working in progress...';
                    end
                end
            end
        end
    end

    %% RMRC motion for IBVS
    methods (Access = public)
        function RMRCVisualServoing(app,robot,q0,cam,Tr,pStar,P,fps,lambda)
            % robot
            % q0 : initial joint state
            % cam : CentralCamera object
            % Tr: transform from end-effector pose -> camera pose
            % pStar: 2xN image target (points in the image plane)
            % P: 3D Cartesian points
            % fps: frame rate
            % lambda : gain of controller

            eMax = 100; % max absolute error on image plane

            while eMax > 15

                % compute the view of the camera
                uv = cam.plot(P);

                % compute image plane error as a column
                e = pStar-uv;   % feature error
                e = e(:);

                % compute the Jacobian
                % exact depth from simulation (not possible in practice)
                pt = homtrans(inv(cam.T), P);
                J = cam.visjac_p(uv, pt(3,:));

                % compute the velocity of camera in camera frame
                try
                    vc = lambda * pinv(J) * e;
                catch
                    % status = -1;
                    return
                end

                % convert velocity of camera in camera frame to world frame
                J12 = [t2r(cam.T) zeros(3); zeros(3) t2r(cam.T)];
                v = J12*vc;

                fprintf('v: %.3f %.3f %.3f %.3f %.3f %.3f\n', v);

                % compute robot's Jacobian and inverse
                J2 = robot.model.jacob0(q0);
                Jinv = pinv(J2);
                % get joint velocities
                qp = Jinv*v;

                % Maximum angular velocity cannot exceed 320 degrees/s
                ind=find(qp>deg2rad(320));
                if ~isempty(ind)
                    qp(ind)=deg2rad(320);
                end
                ind=find(qp<-deg2rad(320));
                if ~isempty(ind)
                    qp(ind)=-deg2rad(320);
                end

                % Check the working condition
                app.CheckWorkingStatus();

                % Update joints
                q = q0 + (1/fps)*qp;
                robot.MoveRobot(q');

                % Get camera location
                Tc = robot.model.fkine(q)*Tr;
                cam.T = Tc;

                q0 = q;

                % update current error
                eMax = max(abs(e));
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.EstopPushed = true;
            app.ResumePushed = false;
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            if app.EstopPushed == true
                app.ResumePushed = true;
                app.EstopPushed = false;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if  ~isempty(findobj('type','figure','name',app.figName))
                if ~isempty(app.robot1) && ~isempty(app.robot2)
                    app.BeaconLightLamp.Color = [1,0,0];

                    %% Set up the vegetable type
                    hold on

                    % initial position above the veggie
                    veggiePose = transl(0,-0.276,0.02);

                    switch app.veggieType
                        case 'Carrot'
                            veggieColor = [255,128,0]/255;

                        case 'Cucumber'
                            veggieColor = [30,170,30]/255;
                    end

                    veggie = Veggie([app.veggieType,'.ply'],veggiePose,veggieColor);

                    %%  Set up for 2 robots
                    view([34.58 27.20])
                    app.WorkStatus.Value = 'Press Enter to start!';
                    pause()
                    app.WorkStatus.Value = 'Working in progress...';

%                     xlim([-0.65 3.56])
%                     ylim([-2.14 1.35])
%                     zlim([-0.05 2.36])
%                     view([17.70 24.49])

                    % minimum max joint speed of the two robots
                    speedMax_Dobot = deg2rad(320);
                    speedMax_Dorna = deg2rad(450);

                    % default poses of the two robots
                    poseDefaultDobot = app.robot1.model.fkine(app.robot1.jointDefault);
                    poseDefaultDorna = app.robot2.model.fkine(app.robot2.jointDefault);

                    %% Dobot reaches the veggie

                    % desired joint state for the dobot at the upper picking position
                    qPickDobot = [-90 45 35 -35 -90]*pi/180;

                    % the pose above the veggie
                    poseAboveVeggieDobot = app.robot1.model.fkine(qPickDobot);

                    % number of desired steps from initial position to the pose
                    % above the veggie
                    steps = 50;

                    % possible collision on the path if something enter the unsafe zone:
                    if app.workspaceDobotDetect == true
                        xMax = max(app.pointCloudInsideDobot(:,1)); % find the nearest point to start collision avoidance

                        if 0.08 <= xMax && xMax <= 0.2
                            app.stepCollisionDobot = -42*xMax + 13; % step to start avoiding collision
                            app.stepCollisionDobot = ceil(app.stepCollisionDobot);

                        elseif xMax < 0.08 && -0.22 < xMax
                            app.stepCollisionDobot = 15; % robot stop at step 15

                        else
                            app.stepCollisionDobot = steps + 1; % no collision in the path
                        end
                    else
                        app.stepCollisionDobot = steps + 1; % no collision in the  path
                    end

                    disp("Dobot starts!");
                    app.ActiveRobotTextArea.Value = "Dobot";

                    stepDobot = app.stepCollisionDobot; % a holder for stepCollision_Dobot

                    % movement to the desired pose
                    app.RMRCMotion(app.robot1,poseAboveVeggieDobot,steps,speedMax_Dobot);

                    % try to avoid the collision if possible
                    if stepDobot ~= steps + 1
                        if stepDobot ~= 15
                            app.WorkStatus.Value = 'Try to avoid object...';
                            % lift the arm
                            zLift = 0.2;
                            T_Lift = transl(0,0,zLift)*app.robot1.model.fkine(app.robot1.model.getpos);
                            app.RMRCMotion(app.robot1,T_Lift,25,speedMax_Dobot);

                            % move in plane xy
                            xMove = 0.08;
                            yMove = 0.08;
                            T_xyMove = transl(-xMove,-yMove,0)*T_Lift;
                            app.RMRCMotion(app.robot1,T_xyMove,25,speedMax_Dobot);

                        elseif stepDobot == 15
                            app.WorkStatus.Value = 'Cannot avoid this object...';
                            while app.workspaceDobotDetect % wait until there is no collision inside the workspace
                                pause(1)
                            end
                        end

                        % continue to the desired pose
                        app.WorkStatus.Value = 'Working in progress...';
                        app.RMRCMotion(app.robot1,poseAboveVeggieDobot,steps,speedMax_Dobot);
                    end

                    %% Lower the arm to pick the object

                    % Gripped object's transform seen by the Dobot's end-effector
                    objectTrDobot = transl(0,0,-0.040);

                    posePick = transl(0,0,-0.04)*poseAboveVeggieDobot;

                    app.robot1.MoveGripper(25);
                    app.RMRCMotion(app.robot1,posePick,20,speedMax_Dobot);
                    app.robot1.MoveGripper(24);

                    %% Bring the veggie to the chopping position

                    % lift the veggie up
                    app.RMRCMotion(app.robot1,poseAboveVeggieDobot,20,speedMax_Dobot,veggie,objectTrDobot);

                    % if the collision object is still in the working zone, find a simple
                    % way to avoid it
                    if app.workspaceDobotDetect == true
                        xMin = min(app.pointCloudInsideDobot(:,1)); % find the nearest point to start collision avoidance

                        if xMin < 0.15 && 0.13 < xMin % object at a possible position to avoid
                            app.WorkStatus.Value = 'Try to avoid object...';

                            % if there is way to avoid collision, go back
                            % that way, otherwise create new waypoints
                            if exist('T_Lift','var') && exist('T_xyMove','var')
                                T1 = T_xyMove;
                                T2 = T_Lift;
                            else
                                T1 = transl(0.1137,-0.1232,0.3017)*rpy2tr(0,0,2.6389);
                                T2 = transl(0.1937,-0.0432,0.3017)*rpy2tr(0,0,2.6389);
                            end
                            app.RMRCMotion(app.robot1,T1,25,speedMax_Dobot,veggie,objectTrDobot);
                            app.RMRCMotion(app.robot1,T2,25,speedMax_Dobot,veggie,objectTrDobot);

                        elseif xMin < 0.13 && -0.05 < xMin
                            app.WorkStatus.Value = 'Cannot avoid this object...';
                            while app.workspaceDobotDetect % wait until there is no collision inside the workspace
                                pause(1)
                            end
                        end

                        app.WorkStatus.Value = 'Working in progress...';
                    end

                    % rotate the waist to the cutting board
                    app.RMRCMotion(app.robot1,transl(0,0,0.04)*poseDefaultDobot,50,speedMax_Dobot,veggie,objectTrDobot);

                    % lower it down
                    app.RMRCMotion(app.robot1,poseDefaultDobot,20,speedMax_Dobot,veggie,objectTrDobot);
                    app.robot1.MoveRobot(app.robot1.jointDefault);

                    %% Replace the veggie with veggie pieces

                    veggiePoseCurrent = veggie.pose;
                    delete(veggie.mesh_h);
                    delete(veggie);
                    hold on

                    veggiePiece = Veggie.empty(6,0);
                    for i = 1:6
                        veggiePiece(i) = Veggie([app.veggieType,'Piece_',num2str(i),'.ply'],veggiePoseCurrent,veggieColor);
                    end

                    %% Dorna 2 joins
                    disp("Dorna starts!");
                    app.ActiveRobotTextArea.Value = "Dorna";

                    % Gripped object's transform seen by the Dorna's end-effector
                    objectTrDorna = transl(0.228,0,0.155)*rpy2tr(-2*pi,-pi/2,2*pi);

                    poseFirstDorna = nan(4,4,5);
                    poseSecondDorna = nan(4,4,5);

                    qFirstDorna =   [0,-61,82,-20,0] * pi/180;

                    % top right corner
                    % poseDefaultDorna = app.robot2.model.fkine(app.robot2.jointDefault);

                    % top left corner
                    poseFirstDorna(:,:,1) = app.robot2.model.fkine(qFirstDorna);

                    % bottom left corner
                    poseSecondDorna(:,:,1) = transl([0 0 -0.04]) * poseFirstDorna(:,:,1);

                    % bottom right corner
                    poseThirdDorna = transl([0 0 -0.05]) * poseDefaultDorna;

                    % thickness of each slice
                    slice = 0.017;

                    % pause()
                    for i = 1:4
                        poseFirstDorna(:,:,i+1) = transl(-slice,0,0)*poseFirstDorna(:,:,i);
                        poseSecondDorna(:,:,i+1) = transl(-slice,0,0)*poseSecondDorna(:,:,i);
                    end

                    % cutting operation
                    for i =1:5
                        % reach the top left
                        app.RMRCMotion(app.robot2,poseFirstDorna(:,:,i),40,speedMax_Dorna);

                        % chop down (bottom left)
                        app.RMRCMotion(app.robot2,poseSecondDorna(:,:,i),40,speedMax_Dorna);

                        % adjust the transform of each veggie piece
                        adjustTr = transl(0,0,-(slice+0.005)*(i-1))*objectTrDorna;
                        % push the veggie piece towards the bowl (bottom right)
                        app.RMRCMotion(app.robot2,poseThirdDorna,40,speedMax_Dorna,veggiePiece(i),adjustTr);

                        % veggie piece falls inside the bow
                        RandominBowl(veggiePiece(i));

                        % go back initial position(top right)
                        app.RMRCMotion(app.robot2,poseDefaultDorna,40,speedMax_Dorna);
                    end

                    %% Dobot bring the final piece closer
                    app.ActiveRobotTextArea.Value = "Dobot";

                    % lift it up
                    app.RMRCMotion(app.robot1,transl(0,0,0.04)*poseDefaultDobot,20,speedMax_Dobot,veggiePiece(6),objectTrDobot);

                    % place it down
                    app.RMRCMotion(app.robot1,transl(0.1,0,0)*poseDefaultDobot,20,speedMax_Dobot,veggiePiece(6),objectTrDobot);
                    app.robot1.MoveGripper(25);

                    % back to the default position
                    app.RMRCMotion(app.robot1,poseDefaultDobot,40,speedMax_Dobot);
                    app.robot1.MoveGripper(0);

                    %% Dorna push the final piece down
                    app.ActiveRobotTextArea.Value = "Dorna";

                    % how the Dorna see the final piece
                    adjustTrFinal = transl(0,0,-(slice+0.006)*6)*objectTrDorna;

                    % top left
                    app.RMRCMotion(app.robot2,transl(-0.1,0,0)*poseDefaultDorna,50,speedMax_Dorna);

                    % bottom left
                    app.RMRCMotion(app.robot2,transl(-0.1,0,-0.06)*poseDefaultDorna,50,speedMax_Dorna);

                    % bottom right
                    app.RMRCMotion(app.robot2,poseThirdDorna,50,speedMax_Dorna,veggiePiece(6),adjustTrFinal);

                    RandominBowl(veggiePiece(6));

                    % back to default position
                    app.RMRCMotion(app.robot2,poseDefaultDorna,100,speedMax_Dorna);

                    app.BeaconLightLamp.Color = [0.39,0.83,0.07]; % turn green when done
                    app.ActiveRobotTextArea.Value = "";
                    app.WorkStatus.Value = 'Finish!';
                end
            end
        end

        % Value changed function: LightCurtainsInformSwitch, 
        % ...and 1 other component
        function LightCurtainSwitchValueChanged(app, event)
            value = app.LightCurtainsInformSwitch.Value;

            % simulate the points cloud of the light curtain
            if value == "On"
                x = linspace(-0.15,0.8,app.gridSize)';
                yLeft = -0.72;
                yRight = 0.37;
                z = linspace(-0.15,0.45,app.gridSize)';

                % Left Light Curtain
                app.gridLeft= nan(app.gridSize^2,3);
                app.gridLeft(:,1) = repmat(x,app.gridSize,1);
                app.gridLeft(:,2) = ones(app.gridSize^2,1)*yLeft;

                for i = 1:app.gridSize
                    app.gridLeft(1+app.gridSize*(i-1):app.gridSize*i,3) = z(i);
                end

                % Right Light Curtain
                app.gridRight= nan(app.gridSize^2,3);
                app.gridRight(:,1) = repmat(x,app.gridSize,1);
                app.gridRight(:,2) = ones(app.gridSize^2,1)*yRight;

                for i = 1:app.gridSize
                    app.gridLeft(1+app.gridSize*(i-1):app.gridSize*i,3) = z(i);
                end

                app.lightCurtainON = true;
                app.textLightCurtainInfo.Value = "LightCurtain ON";
            end

            if value == "Off"
                app.gridLeft = nan(app.gridSize^2,3);
                app.gridRight = nan(app.gridSize^2,3);

                app.lightCurtainON = false;
                app.textLightCurtainInfo.Value = "LightCurtain OFF";
            end
        end

        % Callback function: SetupEnvironmentButton, WorkStatus
        function SetupEnvironmentButtonPushed(app, event)
            close all
            figure('name',app.figName);
            hold on
            CreateEnvironment();

            % create the robots' working zone pointclouds
            app.CreateRobotWorkingZone();
            app.WorkStatus.Value = 'READY TO WORK';
        end

        % Button pushed function: LoadCollisionObjectButton
        function LoadCollisionObjectButtonPushed(app, event)
            if  ~isempty(findobj('type','figure','name',app.figName))
                if isempty(app.collisionObject)
                    app.collisionObject = HumanArm();
                    app.collisionObject.model.base = transl(0.28,-1.2,0.2);
                    app.collisionObject.MoveArm(0);
                    hold on
                    % ellipsoid(app.collisionObject.center(1),app.collisionObject.center(2),app.collisionObject.center(3),app.collisionObject.radii(1),app.collisionObject.radii(2),app.collisionObject.radii(3))
                end
            end
        end

        % Value changed function: SimulatedArmPositionSlider, 
        % ...and 1 other component
        function PositionSliderValueChanged(app, event)
            app.textWorkSpaceInfo.Value = "";
            if  ~isempty(findobj('type','figure','name',app.figName))
                if ~isempty(app.collisionObject)
                    % rotating the simulated arm
                    q = deg2rad(app.SimulatedArmPositionSlider.Value);
                    app.collisionObject.MoveArm(q);

                    % retrieve the current base position of the arm
                    poseBase = app.collisionObject.model.base;

                    % check for collision if the light curtains are on
                    if app.lightCurtainON == true
                        app.pointCloudInsideDobot = [];
                        app.pointCloudInsideDorna = [];

                        % relative movements of the curtains'pointclouds
                        % with respect to the simulated arm
                        gridPointsAndOnesLeft = (trotz(-q)/poseBase*[app.gridLeft,ones(size(app.gridLeft,1),1)]')';
                        updatedGridPointsLeft = gridPointsAndOnesLeft(:,1:3);
                        % plot3(updatedGridPointsLeft(:,1),updatedGridPointsLeft(:,2),updatedGridPointsLeft(:,3));

                        gridPointsAndOnesRight = (trotz(-q)/poseBase*[app.gridRight,ones(size(app.gridRight,1),1)]')';
                        updatedGridPointsRight = gridPointsAndOnesRight(:,1:3);

                        % check for collision
                        algebraicDistLeft = GetAlgebraicDist(updatedGridPointsLeft, app.collisionObject.center, app.collisionObject.radii);
                        pointsInsideLeft = find(algebraicDistLeft <= 1, 1);

                        algebraicDistRight = GetAlgebraicDist(updatedGridPointsRight, app.collisionObject.center, app.collisionObject.radii);
                        pointsInsideRight = find(algebraicDistRight <= 1, 1);

                        % display result
                        if ~isempty(pointsInsideLeft) || ~isempty(pointsInsideRight)
                            app.textWorkSpaceInfo.Value = "Something enters workspace. Stop work!";
                            app.lightCurtainDetect = true;
                        else
                            app.textWorkSpaceInfo.Value = "Safe Work";
                            app.lightCurtainDetect = false;
                        end
                    end

                    % check for collision if the light curtains are off
                    if app.lightCurtainON == false
                        % relative movements of the robots' working
                        % pointclouds with respect to the simulated arm
                        pcDobotandOnes = (trotz(-q)/poseBase*[app.pointCloudDobot,ones(size(app.pointCloudDobot,1),1)]')';
                        updatedDobotPoints = pcDobotandOnes(:,1:3);
                        % plot3(updatedDobotPoints(:,1),updatedDobotPoints(:,2),updatedDobotPoints(:,3));

                        pcDornandOnes = (trotz(-q)/poseBase*[app.pointCloudDorna,ones(size(app.pointCloudDorna,1),1)]')';
                        updatedDornaPoints = pcDornandOnes(:,1:3);

                        % check for collision
                        algebraicDistDobot = GetAlgebraicDist(updatedDobotPoints, app.collisionObject.center, app.collisionObject.radii);
                        pointsInsideDobot = find(algebraicDistDobot <= 1, 1);

                        algebraicDistDorna = GetAlgebraicDist(updatedDornaPoints, app.collisionObject.center, app.collisionObject.radii);
                        pointsInsideDorna = find(algebraicDistDorna <= 1, 1);

                        % display result
                        if ~isempty(pointsInsideDobot)
                            app.workspaceDobotDetect = true;
                            app.pointCloudInsideDobot = app.pointCloudDobot(pointsInsideDobot,:);
                            % disp(app.pointCloudInsideDobot);
                            disp('Possible Dobot collision detect!');
                        else
                            app.workspaceDobotDetect = false;
                            app.pointCloudInsideDobot = [];
                        end

                        if ~isempty(pointsInsideDorna)
                            app.workspaceDornaDetect = true;
                            app.pointCloudInsideDorna = app.pointCloudDorna(pointsInsideDorna,:);
                            % disp(app.pointCloudInsideDorna);
                            disp('Possible Dorna collision detect!');
                        else
                            app.workspaceDornaDetect = false;
                            app.pointCloudInsideDorna = [];          
                        end
                    end
                end
            end
        end

        % Button pushed function: InstallResetRobotsButton
        function InstallResetRobotsButtonPushed(app, event)
            if  ~isempty(findobj('type','figure','name',app.figName))

                % call/reset the position of the two robots
                if isempty(app.robot1)
                    % make sure there's no Dobot object figure already exists
                    h_Dobot = findobj('Tag','DobotMagicianwithGripper');
                    h_DobotGripper1 = findobj('Tag','Dobot Gripper Finger 1');
                    h_DobotGripper2 = findobj('Tag','Dobot Gripper Finger 2');

                    delete(h_Dobot);
                    delete(h_DobotGripper1);
                    delete(h_DobotGripper2);

                    app.robot1 = DobotMagicianwithGripper;
                else
                    app.robot1.MoveRobot(app.robot1.jointDefault);
                end

                if isempty(app.robot2)
                    % make sure there's no Dorna object figure already exists
                    h_Dorna = findobj('Tag','Dorna2Robot');
                    delete(h_Dorna);

                    app.robot2 = Dorna2Robot;
                    % adjust the initial position of the Dorna
                    app.robot2.model.base = transl([0.77 0 0]) * trotz(pi);
                    app.robot2.MoveRobot(app.robot2.model.getpos);
                else
                    app.robot2.MoveRobot(app.robot2.jointDefault);
                end
            end
        end

        % Button pushed function: TeachDobotButton
        function TeachDobotButtonPushed(app, event)
            if ~isempty(findobj('type','figure','name',app.figName))
                % make sure there's no Dobot object figure already exists
                if ~isempty(app.robot1)
                    h_Dobot = findobj('Tag','DobotMagicianwithGripper');
                    h_DobotGripper1 = findobj('Tag','Dobot Gripper Finger 1');
                    h_DobotGripper2 = findobj('Tag','Dobot Gripper Finger 2');

                    delete(h_Dobot);
                    delete(h_DobotGripper1);
                    delete(h_DobotGripper2);

                end
                DobotTeach
            end
        end

        % Button pushed function: TeachDornaButton
        function TeachDornaButtonPushed(app, event)
            if ~isempty(findobj('type','figure','name',app.figName))
                % make sure there's no Dorna object figure already exists
                if ~isempty(app.robot2)
                    h_Dorna = findobj('Tag',app.robot2.name);
                    delete(h_Dorna);
                end
                DornaTeach
            end
        end

        % Selection changed function: VegetableTypeButtonGroup
        function VegetableTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VegetableTypeButtonGroup.SelectedObject;
            if selectedButton == app.CarrotButton
                app.veggieType = 'Carrot';
            else
                app.veggieType = 'Cucumber';
            end
        end

        % Button pushed function: DemoVisualServoingButton
        function DemoVisualServoingButtonPushed(app, event)
            if ~isempty(findobj('type','figure','name',app.figName))
                if ~isempty(app.robot1) && ~isempty(app.robot2)
                    app.BeaconLightLamp.Color = [1,0,0];
                    app.WorkStatus.Value = 'Working in progress...';
                    %% set points
                    % Create image target (points in the image plane)
                    pStar = [631 400 400 631; 400 400 631 631];

                    %Create four 3D points on Dorna 2 Robot
                    P=[0.4,0.4,0.4,0.4;
                        -0.025,0.025,0.025,-0.025;
                        0.25,0.25,0.2,0.2];

                    %% set up camera for Dobot
                    % Add the camera
                    cam = CentralCamera('focal', 0.08, 'pixel', 10e-5, ...
                        'resolution', [1024 1024], 'centre', [512 512],'name', 'DobotCamera');

                    % frame rate
                    fps = 30;

                    % Define values
                    % gain of the controler
                    lambda = 0.8;

                    %% Display
%                     xlim([-0.29 1.05])
%                     ylim([-0.95 0.83])
%                     zlim([-0.22 1.34])
%                     view([227.15 15.28])
                    

                    % plot camera and points
                    q0 = deg2rad([0 13 50 -20 0])';
                    app.robot1.MoveRobot(q0');

                    Tr = troty(-pi/2)*trotz(pi/2); % transform from end-effector pose -> camera pose
                    Tc0 = app.robot1.model.fkine(q0)* Tr;
                    cam.T = Tc0;

                    % Display points in 3D and the camera
                    % cam.plot_camera('Tcam',Tc0,'scale',0.05);
                    h = patch(P(1,:),P(2,:),P(3,:),'b');
%                     lighting gouraud
%                     light
                    pause();
                    %% Dorna moves 1
                    T1 = app.robot2.model.fkine(app.robot2.jointDefault);
                    TrDorna = ctraj(T1,transl(0.07,0,0)*T1,200);
                    qDorna = app.robot2.model.ikcon(TrDorna,app.robot2.jointDefault);
                    
                    app.ActiveRobotTextArea.Value = "Dorna";
                    % Dorna and its points move
                    for i = 1:numrows(qDorna)
                        app.robot2.MoveRobot(qDorna(i,:))
                        P = homtrans(transl(0.07/200,0,0),P);
                        h.Vertices =  homtrans(transl(0.07/200,0,0),h.Vertices')';
                    end

                    %% Project points to the image
                    cam.clf()
                    cam.plot(P, 'Tcam', Tc0);
                    cam.hold(true);
                    cam.plot(pStar, '*'); % create the camera view

                    %% Dobot tracks the image by RMRC 1
                    app.ActiveRobotTextArea.Value = "Dobot";
                    app.RMRCVisualServoing(app.robot1,q0,cam,Tr,pStar,P,fps,lambda);

                    %% Dorna moves 2
                    app.ActiveRobotTextArea.Value = "Dorna";
                    for i = numrows(qDorna):-1:1
                        app.robot2.MoveRobot(qDorna(i,:))
                        P = homtrans(transl(-0.07/200,0,0),P);
                        h.Vertices =  homtrans(transl(-0.07/200,0,0),h.Vertices')';
                    end

                    %% Dobot tracks the image by RMRC 2
                    q1 = app.robot1.model.getpos()';
                    app.RMRCVisualServoing(app.robot1,q1,cam,Tr,pStar,P,fps,lambda);
                    pause(2)
                    delete(h);
                    app.BeaconLightLamp.Color = [0.39,0.83,0.07]; % turn green when done
                    app.ActiveRobotTextArea.Value = "";
                    app.WorkStatus.Value = 'Finish!';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PositionSliderLabel = uilabel(app.UIFigure);
            app.PositionSliderLabel.Text = 'Position';
            app.textWorkSpaceInfo = uitextarea(app.UIFigure);
            app.textWorkSpaceInfo.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.textLightCurtainInfo = uitextarea(app.UIFigure);
            app.textLightCurtainInfo.ValueChangedFcn = createCallbackFcn(app, @LightCurtainSwitchValueChanged, true);
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.SimulatedArmPositionSlider = uislider(app.UIFigure);
            app.SimulatedArmPositionSlider.Limits = [-180 180];
            app.SimulatedArmPositionSlider.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.LoadCollisionObjectButton = uibutton(app.UIFigure, 'push');
            app.LoadCollisionObjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCollisionObjectButtonPushed, true);
            app.LoadCollisionObjectButton.Text = 'Load Collision Object';
            app.SetupEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.SetupEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @SetupEnvironmentButtonPushed, true);
            app.SetupEnvironmentButton.Text = 'Setup Environment';
            app.BeaconLightLamp = uilamp(app.UIFigure);
            app.BeaconLightLampLabel = uilabel(app.UIFigure);
            app.BeaconLightLampLabel.Text = 'Beacon Light';
            app.LightCurtainsInformLabel = uilabel(app.UIFigure);
            app.LightCurtainsInformLabel.Text = 'Light Curtains Inform:';
            app.LightCurtainsInformSwitch = uiswitch(app.UIFigure, 'slider');
            app.LightCurtainsInformSwitch.ValueChangedFcn = createCallbackFcn(app, @LightCurtainSwitchValueChanged, true);
            app.InstallResetRobotsButton = uibutton(app.UIFigure, 'push');
            app.InstallResetRobotsButton.ButtonPushedFcn = createCallbackFcn(app, @InstallResetRobotsButtonPushed, true);
            app.InstallResetRobotsButton.Text = 'Install/Reset Robots';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ProjectProperties', 'Images', 'Kitchen.png');
            app.ActiveRobotTextAreaLabel = uilabel(app.UIFigure);
            app.ActiveRobotTextAreaLabel.Text = 'Active Robot';
            app.ActiveRobotTextArea = uitextarea(app.UIFigure);
            app.TeachDobotButton = uibutton(app.UIFigure, 'push');
            app.TeachDobotButton.ButtonPushedFcn = createCallbackFcn(app, @TeachDobotButtonPushed, true);
            app.TeachDobotButton.Text = 'Teach Dobot';
            app.TeachDornaButton = uibutton(app.UIFigure, 'push');
            app.TeachDornaButton.ButtonPushedFcn = createCallbackFcn(app, @TeachDornaButtonPushed, true);
            app.TeachDornaButton.Text = 'Teach Dorna';
            app.VegetableTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.VegetableTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VegetableTypeButtonGroupSelectionChanged, true);
            app.VegetableTypeButtonGroup.Title = 'Vegetable Type';
            app.CarrotButton = uitogglebutton(app.VegetableTypeButtonGroup);
            app.CarrotButton.Text = 'Carrot';
            app.CarrotButton.Value = true;
            app.CucumberButton = uitogglebutton(app.VegetableTypeButtonGroup);
            app.CucumberButton.Text = 'Cucumber';
            app.WorkStatus = uitextarea(app.UIFigure);
            app.WorkStatus.ValueChangedFcn = createCallbackFcn(app, @SetupEnvironmentButtonPushed, true);
            app.STATUSLabel = uilabel(app.UIFigure);
            app.STATUSLabel.Text = 'STATUS:';
            app.DemoVisualServoingButton = uibutton(app.UIFigure, 'push');
            app.DemoVisualServoingButton.ButtonPushedFcn = createCallbackFcn(app, @DemoVisualServoingButtonPushed, true);
            app.DemoVisualServoingButton.Text = 'Demo Visual Servoing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KitchenRobots

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end