classdef TestCollisionDetect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button2    matlab.ui.control.Button
        Button     matlab.ui.control.Button
        Knob       matlab.ui.control.Knob
        KnobLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        % set path
        path = addpath(genpath('./ProjectProperties'));
        arm = HumanArm % Description
        gridLeft = nan(10000,3)
        h
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Knob
        function KnobValueChanging(app, event)
            q = deg2rad(event.Value);
            app.arm.MoveArm(q);
            poseBase = app.arm.model.base;
%             poseEnd = app.arm.model.fkine(app.arm.model.getpos);
            if ~isempty(app.gridLeft)
                gridPointsAndOnes = (trotz(-q)*inv(poseBase)*[app.gridLeft,ones(size(app.gridLeft,1),1)]')';
                updatedGridPoints = gridPointsAndOnes(:,1:3);
%                 if ~isempty(app.h)
%                     delete(app.h);
%                 end
%                 plot3(updatedGridPoints(:,1),updatedGridPoints(:,2),updatedGridPoints(:,3));
                algebraicDist = GetAlgebraicDist(updatedGridPoints, app.arm.center, app.arm.radii);
                pointsInside = find(algebraicDist <= 1, 1);

                if ~isempty(pointsInside)
                    disp('Collision !');
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            gridSize = 50;
            x = linspace(-0.15,0.8,gridSize)';
            y1 = -0.72;
            z = linspace(-0.15,0.45,gridSize)';

            app.gridLeft= nan(gridSize^2,3);

            app.gridLeft(:,1) = repmat(x,gridSize,1);
            app.gridLeft(:,2) = ones(gridSize^2,1)*y1;

            for i = 1:gridSize
                app.gridLeft(1+gridSize*(i-1):gridSize*i,3) = z(i);
            end
            hold on
            plot3(app.gridLeft(:,1),app.gridLeft(:,2),app.gridLeft(:,3));
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            app.arm.model.base = transl(0.5,-1.3,0.2);
            app.arm.MoveArm(0);
            hold on
            ellipsoid(app.arm.center(1),app.arm.center(2),app.arm.center(3),app.arm.radii(1),app.arm.radii(2),app.arm.radii(3));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 360];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestCollisionDetect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end